classdef GUI4VisulalizingAVSynch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DisconnectDeviceButton        matlab.ui.control.Button
        StartStopdisplayingButton_2   matlab.ui.control.StateButton
        SaveDataButton                matlab.ui.control.Button
        UpdateSpeedDropDown           matlab.ui.control.DropDown
        UpdateSpeedDropDownLabel      matlab.ui.control.Label
        DisplayDurationDropDown       matlab.ui.control.DropDown
        DisplayDurationDropDownLabel  matlab.ui.control.Label
        ConnecttodeviceButton         matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function plotDataAvailable(~)
%             [data, timestamps, ~] = read(app.UIFigure.UserData.qr, app.UIFigure.UserData.dq.ScansAvailableFcnCount, "OutputFormat", "Matrix");
%             plot(timestamps, data);
        end

        function  FlushData(app)
            flush(app.UIFigure.UserData.dq) ;
        end

        function UpdateBuffer(app)
            [data, timestamps, ~] = read( app.UIFigure.UserData.dq,  round(app.UIFigure.UserData.dq.Rate*app.UIFigure.UserData.DispUpdateRate), "OutputFormat", "Matrix"); % Build Buffer
%             app.UIFigure.UserData.StoredData(app.UIFigure.UserData.index+(1:length(timestamps)),:)=[timestamps, data];
%             app.UIFigure.UserData.index=app.UIFigure.UserData.index+length(timestamps    );
            app.UIFigure.UserData.DataBuffer4plot=[app.UIFigure.UserData.DataBuffer4plot(length(data(:,1)):end,:);data];
            app.UIFigure.UserData.BufferTime4plot= [app.UIFigure.UserData.BufferTime4plot(length(data(:,1)):end,:);timestamps];
        end

        function UpdatePlots(app)
            %Acquire more data and update it. Notice that only the x data and ydata are updated to improve the performance.
            UpdateBuffer(app);
            app.UIFigure.UserData.h1.XData=app.UIFigure.UserData.BufferTime4plot;
            app.UIFigure.UserData.h2.XData=app.UIFigure.UserData.BufferTime4plot;
            app.UIFigure.UserData.h1.YData= app.UIFigure.UserData.DataBuffer4plot(:,1);
            app.UIFigure.UserData.h2.YData= app.UIFigure.UserData.DataBuffer4plot(:,2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttodeviceButton
        function ConnecttodeviceButtonPushed(app, event)
            daqreset;
            app.UIFigure.UserData.index=1;
            app.UIFigure.UserData.dq = daq("ni"); %Create daq Object
            addinput(app.UIFigure.UserData.dq, "NI6363", "ai0", "Voltage"); %for measuring the audio feed
            addinput(app.UIFigure.UserData.dq, "NI6363", "ai1", "Voltage"); % for measuring the video feed
            app.ConnecttodeviceButton.Text    ='Connected...';
            app.ConnecttodeviceButton.FontColor='b';
            app.UIFigure.UserData.dq.Rate = 44100; %Sample AV channels at 44.1KHz (which is the audio frequency)
            app.StartStopdisplayingButton_2.Visible='on';
            app.ConnecttodeviceButton.Enable=0;
            app.UIFigure.UserData.Appstart=true;
            app.UIFigure.UserData.StoredData=zeros(round(app.UIFigure.UserData.dq.Rate)*1000,3);

        end

        % Callback function
        function StartStopdisplayingButtonPushed(app, event)

        end

        % Value changed function: StartStopdisplayingButton_2
        function StartStopdisplayingButton_2ValueChanged(app, event)
            value = app.StartStopdisplayingButton_2.Value;
            while value && app.UIFigure.UserData.Appstart

                if app.UIFigure.UserData.dq.Running==false % If the daq acquision is not running, Start it once, acquire intial data and plot it

                    app.UIFigure.UserData.DispDur= str2double(extractBefore(app.DisplayDurationDropDown.Value, ' s'));
                    app.UIFigure.UserData.DispUpdateRate= str2double(extractBefore(app.UpdateSpeedDropDown.Value, ' s'));
                    app.UIFigure.UserData.DataBuffer4plot=zeros(ceil(app.UIFigure.UserData.dq.Rate*app.UIFigure.UserData.DispDur),2);
                    app.UIFigure.UserData.BufferTime4plot=zeros(ceil(app.UIFigure.UserData.dq.Rate*app.UIFigure.UserData.DispDur),1);

                    start(app.UIFigure.UserData.dq, "Duration", seconds(1000));
                    UpdateBuffer(app);
                    cla(app.UIAxes);
                    app.UIFigure.UserData.h1= plot(app.UIAxes, app.UIFigure.UserData.BufferTime4plot,app.UIFigure.UserData.DataBuffer4plot(:,1));
                    hold(app.UIAxes);
                    app.UIFigure.UserData.h2=plot(app.UIAxes, app.UIFigure.UserData.BufferTime4plot,app.UIFigure.UserData.DataBuffer4plot(:,2));

                 
                else     
                    %Acquire more data and update it. Notice that only the x data and ydata are updated to improve the performance.
                UpdateBuffer(app);
                UpdatePlots(app);  
                app.UIAxes.XLim=[(min(app.UIFigure.UserData.BufferTime4plot)), (max(app.UIFigure.UserData.BufferTime4plot)) ];
                pause(app.UIFigure.UserData.DispUpdateRate)
                end
            
            end
            if  value==0
                app.UIFigure.UserData.Appstart=false;
                app.UIFigure.UserData.dq.stop()
                FlushData(app)

            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.UIFigure.UserData.dq.stop()
            delete(app)
        end

        % Value changed function: DisplayDurationDropDown
        function DisplayDurationDropDownValueChanged(app, event)

        end

        % Value changed function: UpdateSpeedDropDown
        function UpdateSpeedDropDownValueChanged(app, event)

        end

        % Button pushed function: DisconnectDeviceButton
        function DisconnectDeviceButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnecttodeviceButton = uibutton(app.UIFigure, 'push');
            app.ConnecttodeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttodeviceButtonPushed, true);
            app.ConnecttodeviceButton.Text = 'Connect to device';
            app.DisplayDurationDropDownLabel = uilabel(app.UIFigure);
            app.DisplayDurationDropDownLabel.Text = 'Display Duration';
            app.DisplayDurationDropDown = uidropdown(app.UIFigure);
            app.DisplayDurationDropDown.Items = {'0.10 s', '0.25 s', '0.50 s', '0.75 s', '1.00 s', '1.25 s', '1.50 s', '2.00 s', '2.50 s', '3.00 s', '4.00 s', '5.00 s', '10.00 s'};
            app.DisplayDurationDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDurationDropDownValueChanged, true);
            app.DisplayDurationDropDown.Value = '5.00 s';
            app.UpdateSpeedDropDownLabel = uilabel(app.UIFigure);
            app.UpdateSpeedDropDownLabel.Text = 'UpdateSpeed';
            app.UpdateSpeedDropDown = uidropdown(app.UIFigure);
            app.UpdateSpeedDropDown.Items = {'0.10 s', '0.25 s', '0.50 s', '0.75 s', '1.00 s', '1.25 s', '1.50 s', '2.00 s', '2.50 s', '3.00 s', '4.00 s', '5.00 s', ''};
            app.UpdateSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateSpeedDropDownValueChanged, true);
            app.UpdateSpeedDropDown.Value = '0.50 s';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.Text = 'SaveData';
            app.StartStopdisplayingButton_2 = uibutton(app.UIFigure, 'state');
            app.StartStopdisplayingButton_2.ValueChangedFcn = createCallbackFcn(app, @StartStopdisplayingButton_2ValueChanged, true);
            app.StartStopdisplayingButton_2.Visible = 'off';
            app.StartStopdisplayingButton_2.Text = 'Start/Stop displaying';
            app.DisconnectDeviceButton = uibutton(app.UIFigure, 'push');
            app.DisconnectDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectDeviceButtonPushed, true);
            app.DisconnectDeviceButton.Text = 'Disconnect Device';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI4VisulalizingAVSynch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end